classdef buildTowerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RunTab                        matlab.ui.container.Tab
        Button_6                      matlab.ui.control.Button
        SpeedKnobLabel                matlab.ui.control.Label
        SpeedKnob                     matlab.ui.control.DiscreteKnob
        ReadincurrentpositionButton   matlab.ui.control.Button
        CompetitionSettingsButton     matlab.ui.control.Button
        mmLabel                       matlab.ui.control.Label
        mmLabel_2                     matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        thetaEditField                matlab.ui.control.NumericEditField
        ConnectButton                 matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        BuildtowerButton              matlab.ui.control.Button
        NumberofBlocksEditFieldLabel  matlab.ui.control.Label
        NumberofBlocksEditField       matlab.ui.control.NumericEditField
        BuildTimeTextArea             matlab.ui.control.TextArea
        LoadSideSwitch_2Label         matlab.ui.control.Label
        LoadSideSwitch_2              matlab.ui.control.Switch
        DisconnectButton              matlab.ui.control.Button
        JogModeTab                    matlab.ui.container.Tab
        Button                        matlab.ui.control.Button
        vButton                       matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        vButton_2                     matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        vButton_3                     matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        vButton_5                     matlab.ui.control.Button
        XLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        GRIPButton                    matlab.ui.control.StateButton
        JogSpeedSliderLabel           matlab.ui.control.Label
        JogSpeedSlider                matlab.ui.control.Slider
        PassiveReadTab                matlab.ui.container.Tab
        TestLampLabel                 matlab.ui.control.Label
        TestLamp                      matlab.ui.control.Lamp
        Test2LampLabel                matlab.ui.control.Label
        Test2Lamp                     matlab.ui.control.Lamp
        Test2onButton                 matlab.ui.control.Button
        StartswitchingButton          matlab.ui.control.Button
        TuningTab                     matlab.ui.container.Tab
    end


    properties (Access = public)
        serial % Description
    end

    properties (Access = private)
        connectionStatus = 0 % Is robot connected
        run = 0; % Description
    end

    methods (Access = private)
    
        function updateConnectionStatus(app, newStatus)
            % New status: 1 if connected, 0 if not connected
            app.connectionStatus = newStatus;
            if newStatus
                app.serial = selectSerial();
                
                app.ReadincurrentpositionButton.Enable = 'on'; % Enable Read in position
                
                % Hide connect button
                app.ConnectButton.Enable = 'off'; % disable connect button
                app.ConnectButton.Visible = 'off'; % hide connect button
                
                % Enable Build Button
                app.BuildtowerButton.Enable = 'on'; % Enable build tower button
                
                % Show Disconnect button
                app.DisconnectButton.Enable = 'on';
                app.DisconnectButton.Visible = 'on';
                
            elseif ~newStatus
                app.closeSerial();
                
                % Hide disconnect button
                app.DisconnectButton.Enable = 'off';
                app.DisconnectButton.Visible = 'off';
                
                % Show connect button
                app.ConnectButton.Enable = 'on'; % enable connect button
                app.ConnectButton.Visible = 'on'; % show connect button
                
                % Disable build tower button
                app.BuildtowerButton.Enable = 'off'; % Enable build tower button
                
            end
        end
        
        function updateRunStatus(app, newRun)
            app.run = newRun; 
            if newRun
                % Hide Build tower button
                app.BuildtowerButton.Visible = 'off';
                app.BuildtowerButton.Enable = 'off';
                
                % Show stop button
                app.STOPButton.Visible = 'on';
                app.STOPButton.Enable = 'on';
                
                % Show Build time 
                app.BuildTimeTextArea.Value = {''};
                app.BuildTimeTextArea.Enable = 'on';
                app.BuildTimeTextArea.Visible = 'on';
                
            elseif ~newRun
                % Disconnect
                app.updateConnectionStatus(0); 
                
                % Show Build tower button
                app.BuildtowerButton.Visible = 'on';
                app.BuildtowerButton.Enable = 'on';
                
                % Hide stop button
                app.STOPButton.Visible = 'off';
                app.STOPButton.Enable = 'off';
            end
        end
        
        function closeSerial(app)
            disp('Quitting')
            fclose(app.serial);
            delete(app.serial);
            clear serial    
        end
        
        function setBuildSettings(app, x,y,theta,loadSide,speed, nBlocks)
            app.XEditField.Value = x;
            app.YEditField.Value = y;
            app.thetaEditField.Value = theta;
            app.LoadSideSwitch_2.Value = loadSide;
            app.SpeedKnob.Value = speed;
            app.NumberofBlocksEditField.Value = nBlocks; 
        end
        
        function s = generateTimeString(app,time)
            mins = floor(time/60); 
            seconds = round(time-mins*60);
            if mins>0    
                s = sprintf('%d mins, %d seconds',mins,seconds);
            elseif mins==0
                s = sprintf('%d seconds',seconds);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clear serial
        close all
        debuggingVars();
        end

        % Button pushed function: BuildtowerButton
        function BuildtowerButtonPushed(app, event)
            if app.connectionStatus
                app.updateRunStatus(1);  

                % Read in desired build location 
                x = app.XEditField.Value;
                y = app.YEditField.Value;
                theta = app.thetaEditField.Value;
                loadSide = app.LoadSideSwitch_2.Value;
                speed = app.SpeedKnob.Value;
                nBlocks = app.NumberofBlocksEditField.Value;
                disp('Send multiple trajectories and run position control...')
                [nchunks, chunks] = createMotionPlan(x,y,theta,loadSide,speed,nBlocks);
                
                start_time = tic;
                
                for i = 1:nchunks
                    pause(0.01);% Interrupts to allow Run Status to update for stop button
                    if app.run
                        data = chunks(:,:,:,i);
                        xdata = data(:,:,1);
                        ydata = data(:,:,2);
                        zdata = data(:,:,3);
                        thdata = data(:,:,4);
                        gripdata = data(:,:,5);
                        [length,~,~] = size(data);
                        
                        sendTrajectory(app.serial, length, xdata, ydata, zdata, thdata, gripdata);
                        
                        disp('Chunk '+string(i)+' of '+string(nchunks)+' sent.')
                        success = runControl(app.serial, "PC", i);
                        
                        s = app.generateTimeString(toc(start_time));
                        app.BuildTimeTextArea.Value = {s};
                        
                        if ~success 
                            app.updateRunStatus(0);
                            error('Run Control Not Successful')
                        end
                    elseif ~(app.run)
                        s = app.generateTimeString(toc(start_time));
                        app.BuildTimeTextArea.Value = {s};
                        return
                    end
                end
                
                end_time = toc(start_time);
                disp('Duration of motion plan: '+string(end_time)+'s');
                s = app.generateTimeString(end_time);
              
                app.BuildTimeTextArea.Value = {s};
               
                app.updateRunStatus(0); 
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.updateConnectionStatus(1);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.updateRunStatus(0);
        end

        % Button pushed function: CompetitionSettingsButton
        function CompetitionSettingsButtonPushed(app, event)
            app.setBuildSettings(200,-50,0,'A','Full Jenghis',54);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.updateConnectionStatus(0);  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.Button_6 = uibutton(app.RunTab, 'push');
            app.Button_6.Icon = 'GUI_background2.png';
            app.Button_6.Text = '';
            app.SpeedKnobLabel = uilabel(app.RunTab);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.RunTab, 'discrete');
            app.SpeedKnob.Items = {'Accurate', 'Balanced', 'Full Jenghis'};
            app.SpeedKnob.Value = 'Balanced';
            app.ReadincurrentpositionButton = uibutton(app.RunTab, 'push');
            app.ReadincurrentpositionButton.Text = {'Read in'; 'current'; 'position'};
            app.CompetitionSettingsButton = uibutton(app.RunTab, 'push');
            app.CompetitionSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @CompetitionSettingsButtonPushed, true);
            app.CompetitionSettingsButton.Text = {'Competition'; ' Settings'};
            app.mmLabel = uilabel(app.RunTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.RunTab);
            app.mmLabel_2.Text = 'mm';
            app.Label_3 = uilabel(app.RunTab);
            app.Label_3.Text = '°';
            app.XEditFieldLabel = uilabel(app.RunTab);
            app.XEditFieldLabel.Text = 'X: ';
            app.XEditField = uieditfield(app.RunTab, 'numeric');
            app.XEditField.Limits = [0 300];
            app.XEditField.Value = 200;
            app.YEditFieldLabel = uilabel(app.RunTab);
            app.YEditFieldLabel.Text = 'Y: ';
            app.YEditField = uieditfield(app.RunTab, 'numeric');
            app.YEditField.Limits = [-300 300];
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Value = -50;
            app.EditFieldLabel = uilabel(app.RunTab);
            app.EditFieldLabel.Text = 'Ɵ: ';
            app.thetaEditField = uieditfield(app.RunTab, 'numeric');
            app.thetaEditField.Limits = [-45 45];
            app.ConnectButton = uibutton(app.RunTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.STOPButton = uibutton(app.RunTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Visible = 'off';
            app.STOPButton.Text = 'STOP';
            app.BuildtowerButton = uibutton(app.RunTab, 'push');
            app.BuildtowerButton.ButtonPushedFcn = createCallbackFcn(app, @BuildtowerButtonPushed, true);
            app.BuildtowerButton.Enable = 'off';
            app.BuildtowerButton.Text = 'Build tower';
            app.NumberofBlocksEditFieldLabel = uilabel(app.RunTab);
            app.NumberofBlocksEditFieldLabel.Text = {'Number of'; ' Blocks:'};
            app.NumberofBlocksEditField = uieditfield(app.RunTab, 'numeric');
            app.NumberofBlocksEditField.Limits = [1 54];
            app.NumberofBlocksEditField.ValueDisplayFormat = '%.0f';
            app.NumberofBlocksEditField.Value = 54;
            app.BuildTimeTextArea = uitextarea(app.RunTab);
            app.BuildTimeTextArea.Editable = 'off';
            app.BuildTimeTextArea.Visible = 'off';
            app.LoadSideSwitch_2Label = uilabel(app.RunTab);
            app.LoadSideSwitch_2Label.Text = 'Load Side';
            app.LoadSideSwitch_2 = uiswitch(app.RunTab, 'slider');
            app.LoadSideSwitch_2.Items = {'A', 'B'};
            app.LoadSideSwitch_2.Value = 'A';
            app.DisconnectButton = uibutton(app.RunTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Visible = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.JogModeTab = uitab(app.TabGroup);
            app.JogModeTab.Title = 'Jog Mode';
            app.Button = uibutton(app.JogModeTab, 'push');
            app.Button.Text = '^';
            app.vButton = uibutton(app.JogModeTab, 'push');
            app.vButton.Text = 'v';
            app.Button_2 = uibutton(app.JogModeTab, 'push');
            app.Button_2.Text = '^';
            app.vButton_2 = uibutton(app.JogModeTab, 'push');
            app.vButton_2.Text = 'v';
            app.Button_3 = uibutton(app.JogModeTab, 'push');
            app.Button_3.Text = '^';
            app.vButton_3 = uibutton(app.JogModeTab, 'push');
            app.vButton_3.Text = 'v';
            app.Button_5 = uibutton(app.JogModeTab, 'push');
            app.Button_5.Text = '^';
            app.vButton_5 = uibutton(app.JogModeTab, 'push');
            app.vButton_5.Text = 'v';
            app.XLabel = uilabel(app.JogModeTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.JogModeTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.JogModeTab);
            app.ZLabel.Text = 'Z';
            app.Label = uilabel(app.JogModeTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.JogModeTab);
            app.Label_2.Text = 'Ɵ';
            app.GRIPButton = uibutton(app.JogModeTab, 'state');
            app.GRIPButton.Text = 'GRIP';
            app.JogSpeedSliderLabel = uilabel(app.JogModeTab);
            app.JogSpeedSliderLabel.Text = 'Jog Speed';
            app.JogSpeedSlider = uislider(app.JogModeTab);
            app.JogSpeedSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.PassiveReadTab = uitab(app.TabGroup);
            app.PassiveReadTab.Title = 'Passive Read';
            app.TestLampLabel = uilabel(app.PassiveReadTab);
            app.TestLampLabel.Text = 'Test';
            app.TestLamp = uilamp(app.PassiveReadTab);
            app.Test2LampLabel = uilabel(app.PassiveReadTab);
            app.Test2LampLabel.Text = 'Test 2';
            app.Test2Lamp = uilamp(app.PassiveReadTab);
            app.Test2onButton = uibutton(app.PassiveReadTab, 'push');
            app.Test2onButton.Text = 'Test 2 on';
            app.StartswitchingButton = uibutton(app.PassiveReadTab, 'push');
            app.StartswitchingButton.Text = 'Start switching';
            app.TuningTab = uitab(app.TabGroup);
            app.TuningTab.Title = 'Tuning';
        end
    end

    methods (Access = public)

        % Construct app
        function app = buildTowerGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end